classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RMSEUIFigure  matlab.ui.Figure
        UITable       matlab.ui.control.Table
        UIAxes        matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % RMSE
            % - table
            t = VizOutputs.getSummaryTable();
            app.UITable.Data = [...
                t.Model, ...
                num2cell(t.All), ...
                num2cell(t.Train), ...
                num2cell(t.Val), ...
                num2cell(t.Test) ...
            ];
            app.UITable.ColumnName = t.Properties.VariableNames;
            % - bar
            VizOutputs.bar(app.UIAxes);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            row = event.Indices(1);
            col = event.Indices(2);
            
            % select first column (`Model`)
            if col == 1
                close('all');
                clc();
            
                dbFilename = fullfile(...
                    VizOutputs.dataDir, ...
                    app.UITable.Data{row, col} ...
                );
                
                VizOutputs.main(dbFilename);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RMSEUIFigure = uifigure;
            app.RMSEUIFigure.Name = 'RMSE';
            app.UITable = uitable(app.RMSEUIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes = uiaxes(app.RMSEUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RMSEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RMSEUIFigure)
        end
    end
end